classdef GUI_Phase1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        PlayButton    matlab.ui.control.Button
        RecordButton  matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Fs = 44800; % 采样率
        % nBits = 16; % 采样位数
        % nChannel = 1; % 音频通道数 1: 单声道 2：立体声
        myVoice = audiorecorder(44800, 16, 1);
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            ax = app.UIAxes; % In order to preserve app.UIAxes
            mv = app.myVoice;
            clc
            clearvars -except ax mv
            close all
            app.UIAxes = ax; 
            app.myVoice = mv;

            time = 5; % 采样时间
            disp('Start Speaking...')
            recordblocking(mv, time);
            disp('End of Speak')
            set(app.myVoice, 'userdata', mv);
            myrecording = getaudiodata(app.myVoice);
            plot(app.UIAxes , myrecording, 'b-', 'LineWidth', 1)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            Fs = 44800; % 频率
            mv = get(app.myVoice, 'userdata');
            myrecording = getaudiodata(mv);
            sound(myrecording, Fs);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            mv = get(app.myVoice, 'userdata');
            myrecording = getaudiodata(mv);
            plot(myrecording, 'b-', 'LineWidth', 1);
            xlabel('Time');
            ylabel('Amplitude');
            title('Audio Signal');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = 0;
            app.UIAxes.YTickLabel = {'0'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Phase1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end