classdef GUI_Phase2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AnalogLowPassFilteringButton  matlab.ui.control.Button
        IdealLowPassFilteringButton   matlab.ui.control.Button
        AddGaussianNoiseButton        matlab.ui.control.Button
        FrequencyAnalsisButton        matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        RecordButton                  matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Fs = 44800; % 采样率
        % nBits = 16; % 采样位数
        % nChannel = 1; % 音频通道数 1: 单声道 2：立体声
        usersProperties = struct('myVoice', audiorecorder(44800, 16, 1), ...
            'myAudiodata', 0.0,'mySpectrum', 0.0)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            ax = app.UIAxes; % In order to preserve app.UIAxes
            ax2 = app.UIAxes_2;
            up = app.usersProperties;
            clc
            clearvars -except ax ax2 up
            close all
            app.UIAxes = ax; 
            app.UIAxes_2 = ax2;
            app.usersProperties = up;
            % app.myVoice = mv; % mv myVoice
            % app.myAudiodata = ma; % ma myAudiodata
            % app.mySpectrum = ms; % ms mySpectrum

            mv = app.usersProperties.myVoice;
            time = 5; % 采样时间
            disp('Start Speaking...')
            recordblocking(mv, time);
            disp('End of Speak')
            % set(app.myVoice, 'userdata', mv);
            ma = getaudiodata(mv); 
            % set(app.myAudiodata, 'userdata', ma);
            ms = fftshift(fft(ma)); 
            % set(app.mySpectrum, 'userdata', ms);
            set(app.usersProperties, 'myVoice', mv);
            set(app.usersProperties, 'myAudiodata', ma);
            set(app.usersProperties, 'mySpectrum', ms);
            %app.usersProperties = setfield(app.usersProperties, ...
                %'myVoice', mv);
            %app.usersProperties = setfield(app.usersProperties, ...
                %'myAudiodata', ma);
            %app.usersProperties = setfield(app.usersProperties, ...
                %'mySpectrum', ms);
            %set(app.usersProperties, 'userdata', app.usersProperties)
            plot(app.UIAxes, ma, 'b-', 'LineWidth', 1)
            plot(app.UIAxes_2, ms, 'b-', 'LineWidth', 1)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            Fs = 44800; % 频率
            % mv = get(app.myVoice, 'userdata');
            % myrecording = getaudiodata(mv);
            up = get(app.usersProperties, 'userdata');
            ma = up.myAudiodata;
            sound(ma, Fs);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            up = get(app.usersProperties, 'userdata');
            ma = up.myAudiodata;
            % mv = get(app.myVoice, 'userdata');
            % maa = getaudiodata(mv);
            plot(ma, 'b-', 'LineWidth', 1);
            xlabel('Time');
            ylabel('Amplitude');
            title('Audio Signal');

            % plot(maa, 'b-', 'LineWidth', 1);
            % xlabel('Time');
            % ylabel('Amplitude');
            % title('Audio Signal');
        end

        % Button pushed function: FrequencyAnalsisButton
        function FrequencyAnalsisButtonPushed(app, event)
            app.myspectrum = fftshift(fft(app.myaudiodata));
            plot(app.UIAxes_2 , app.myspectrum, 'b-', 'LineWidth', 1)
        end

        % Button pushed function: AddGaussianNoiseButton
        function AddGaussianNoiseButtonPushed(app, event)
            
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            up = get(app.usersProperties, 'userdata');
            ms = up.mySpectrum;
            % mv = get(app.myVoice, 'userdata');
            % maa = getaudiodata(mv);
            plot(ms, 'b-', 'LineWidth', 1);
            xlabel('Time');
            ylabel('Amplitude');
            title('Audio Signal');

            % plot(maa, 'b-', 'LineWidth', 1);
            % xlabel('Time');
            % ylabel('Amplitude');
            % title('Audio Signal');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = 0;
            app.UIAxes.YTickLabel = {'0'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Audio Spectrum')
            xlabel(app.UIAxes_2, 'Angular Frequency')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = 0;
            app.UIAxes_2.YTickLabel = {'0'};
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FrequencyAnalsisButton = uibutton(app.UIFigure, 'push');
            app.FrequencyAnalsisButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyAnalsisButtonPushed, true);
            app.FrequencyAnalsisButton.Text = 'Frequency Analsis';
            app.AddGaussianNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddGaussianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddGaussianNoiseButtonPushed, true);
            app.AddGaussianNoiseButton.Text = 'Add Gaussian Noise';
            app.IdealLowPassFilteringButton = uibutton(app.UIFigure, 'push');
            app.IdealLowPassFilteringButton.Text = 'Ideal Low Pass Filtering';
            app.AnalogLowPassFilteringButton = uibutton(app.UIFigure, 'push');
            app.AnalogLowPassFilteringButton.Text = 'Analog Low Pass Filtering';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Phase2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end